classdef Chill2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GaugesButton                  matlab.ui.control.StateButton
        RelativeHumidityGaugeLabel    matlab.ui.control.Label
        OutputTempCGaugeLabel         matlab.ui.control.Label
        InputTempCGaugeLabel          matlab.ui.control.Label
        CurrentAGaugeLabel            matlab.ui.control.Label
        CurrentAGauge                 matlab.ui.control.LinearGauge
        RelativeHumidityGauge         matlab.ui.control.SemicircularGauge
        OutputTempCGauge              matlab.ui.control.SemicircularGauge
        InputTempCGauge               matlab.ui.control.SemicircularGauge
        GraphData                     matlab.ui.control.StateButton
        ReadingVariablesPanel         matlab.ui.container.Panel
        TemOUTField_2                 matlab.ui.control.NumericEditField
        TempOUTButton_2               matlab.ui.control.StateButton
        CurrentField                  matlab.ui.control.NumericEditField
        CurrentButton                 matlab.ui.control.StateButton
        humiField                     matlab.ui.control.NumericEditField
        HUMIButton                    matlab.ui.control.StateButton
        TempINField                   matlab.ui.control.NumericEditField
        TempINCButton                 matlab.ui.control.StateButton
        TempAMBField                  matlab.ui.control.NumericEditField
        TempAMBCButton                matlab.ui.control.StateButton
        ReadDataButton                matlab.ui.control.Button
        ConnectButton                 matlab.ui.control.Button
        StatusLabel                   matlab.ui.control.Label
        ConectionStatusLamp           matlab.ui.control.Lamp
        ConectionStatusLampLabel      matlab.ui.control.Label
        PortDropDown                  matlab.ui.control.DropDown
        PortDropDownLabel             matlab.ui.control.Label
        SearchPortsButton             matlab.ui.control.Button
        ChillerMonitoringSystemLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ii % Description
        comu % Serial Connection
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchPortsButton
        function SearchPortsButtonPushed(app, event)
            % Busca los puertos disponibles, los agrega en la lista
            % desplegable y habilita el Botton Connect.
            app.PortDropDown.Items = serialportlist("all");
            app.PortDropDown.Enable = true;
            app.PortDropDownLabel.Enable = true;
            app.ConnectButton.Enable = true;
            delete(app.comu)
            clear app.comu
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Conecta o Desconeta enlace con el arduino, al mismo tiempo
            % que hablitia o deshabilita otros botones.
            if app.ConnectButton.Text == "Connect"
                app.comu = serialport(app.PortDropDown.Value, 9600);
                app.ConectionStatusLamp.Color = "green";
                app.StatusLabel.Text = "ON";
                app.ConnectButton.Text = "Disconnect";   
                app.ReadDataButton.Enable = true;
                uialert(app.UIFigure,"Arduino Serial Connection Established","Connected","Icon","success")
                app.ii = 0;
            else
                app.ConectionStatusLamp.Color = "red";
                app.StatusLabel.Text = "OFF";
                app.ConnectButton.Text = "Connect";
                app.ReadDataButton.Enable = false;
                app.ReadingVariablesPanel.Enable = false;
                app.GaugesButton.Enable = false;
                cla(app.UIAxes)
                flush(app.comu)
                delete(app.comu)
                clear app.comu
            end
        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            if app.ReadDataButton.Text == "Read Data"
                app.ReadDataButton.Text = "Stop Reading";
                app.ConnectButton.Enable = false;
                app.ReadingVariablesPanel.Enable = true;
                app.GraphData.Enable = true;
                app.GaugesButton.Enable = true;

                % Inicializa lineas animadas
                TempAMBAnLine = animatedline(app.UIAxes,"Color","red","LineWidth",1.5);
                TempINAnLine = animatedline(app.UIAxes,"Color","magenta","LineWidth",1.5);
                TempOUTAnLine = animatedline(app.UIAxes,"Color","black","LineWidth",1.5);
                HumiAnLine = animatedline(app.UIAxes,"Color","blue","LineWidth",1.5);
                CurrentAnLine = animatedline(app.UIAxes,"Color","green","LineWidth",1.5);
              
                while app.ReadDataButton.Text == "Stop Reading"
                    r = rand(1);
                    data = str2num(readline(app.comu));
                    app.TempAMBField.Value = data(1);
                    app.TempINField.Value = data(2);
                    app.TemOUTField_2.Value = data(2)+12+r;
                    app.humiField.Value = data(4);
                    app.CurrentField.Value = data(5);

                    GraphDataValueChanged(app,event)
                    GaugesButtonValueChanged(app,event)

                    if app.GraphData.Text == "Stopped Graphing"   
                        axis(app.UIAxes, [app.ii-100 app.ii 0 70])
                        addpoints(TempAMBAnLine,app.ii,app.TempAMBField.Value)
                        addpoints(TempINAnLine,app.ii,app.TempINField.Value)
                        addpoints(TempOUTAnLine,app.ii,app.TemOUTField_2.Value)
                        addpoints(HumiAnLine,app.ii,app.humiField.Value)                       
                        addpoints(CurrentAnLine,app.ii,app.CurrentField.Value)
                        drawnow limitrate                        
                        app.ii = app.ii+1;
                    end

                    if app.GaugesButton.Text == "Gauging"
                        app.InputTempCGauge.Value = app.TempINField.Value;
                        app.OutputTempCGauge.Value = app.TemOUTField_2.Value;
                        app.RelativeHumidityGauge.Value = app.humiField.Value;
                        app.CurrentAGauge.Value = app.CurrentField.Value;
                    end
                end
            else
                app.ReadDataButton.Text = "Read Data";
                app.ConnectButton.Enable = true;
                app.GraphData.Enable = false;
                app.GaugesButton.Enable = false;
            end
        end

        % Value changed function: GraphData
        function GraphDataValueChanged(app, event)
            value = app.GraphData.Value;            
            if value                
                app.GraphData.Text = "Stopped Graphing";                
            else
                app.GraphData.Text = "Graph Data";
            end            
        end

        % Value changed function: GaugesButton
        function GaugesButtonValueChanged(app, event)
            value = app.GaugesButton.Value;              
            if value 
                app.InputTempCGauge.Enable = true;
                app.OutputTempCGauge.Enable = true;
                app.RelativeHumidityGauge.Enable = true;
                app.CurrentAGauge.Enable = true;
                app.GaugesButton.Text = "Gauging";                
            else
                app.InputTempCGauge.Enable = false;
                app.OutputTempCGauge.Enable = false;
                app.RelativeHumidityGauge.Enable = false;
                app.CurrentAGauge.Enable = false;
                app.GaugesButton.Text = "Gauges";
            end              
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time [seconds]')
            colormap(app.UIAxes, 'hsv')
            app.ChillerMonitoringSystemLabel = uilabel(app.UIFigure);
            app.ChillerMonitoringSystemLabel.Text = 'Chiller Monitoring System';
            app.SearchPortsButton = uibutton(app.UIFigure, 'push');
            app.SearchPortsButton.ButtonPushedFcn = createCallbackFcn(app, @SearchPortsButtonPushed, true);
            app.SearchPortsButton.Text = 'Search Ports';
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Enable = 'off';
            app.PortDropDownLabel.Text = 'Port:';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {'Unknown'};
            app.PortDropDown.Enable = 'off';
            app.PortDropDown.Value = 'Unknown';
            app.ConectionStatusLampLabel = uilabel(app.UIFigure);
            app.ConectionStatusLampLabel.Text = 'Conection Status:';
            app.ConectionStatusLamp = uilamp(app.UIFigure);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'OFF';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.ReadDataButton = uibutton(app.UIFigure, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Enable = 'off';
            app.ReadDataButton.Text = 'Read Data';
            app.ReadingVariablesPanel = uipanel(app.UIFigure);
            app.ReadingVariablesPanel.Enable = 'off';
            app.ReadingVariablesPanel.Title = 'Reading Variables';
            app.TempAMBCButton = uibutton(app.ReadingVariablesPanel, 'state');
            app.TempAMBCButton.Text = 'Temp AMB [Cº]';
            app.TempAMBField = uieditfield(app.ReadingVariablesPanel, 'numeric');
            app.TempAMBField.Limits = [-100 100];
            app.TempINCButton = uibutton(app.ReadingVariablesPanel, 'state');
            app.TempINCButton.Text = 'Temp IN [Cº]';
            app.TempINField = uieditfield(app.ReadingVariablesPanel, 'numeric');
            app.TempINField.Limits = [-100 100];
            app.HUMIButton = uibutton(app.ReadingVariablesPanel, 'state');
            app.HUMIButton.Text = 'Humidity %';
            app.humiField = uieditfield(app.ReadingVariablesPanel, 'numeric');
            app.humiField.Limits = [-100 100];
            app.CurrentButton = uibutton(app.ReadingVariablesPanel, 'state');
            app.CurrentButton.Text = 'Current [A]';
            app.CurrentField = uieditfield(app.ReadingVariablesPanel, 'numeric');
            app.CurrentField.Limits = [-100 100];
            app.TempOUTButton_2 = uibutton(app.ReadingVariablesPanel, 'state');
            app.TempOUTButton_2.Text = 'Temp OUT [Cº]';
            app.TemOUTField_2 = uieditfield(app.ReadingVariablesPanel, 'numeric');
            app.TemOUTField_2.Limits = [-100 100];
            app.GraphData = uibutton(app.UIFigure, 'state');
            app.GraphData.ValueChangedFcn = createCallbackFcn(app, @GraphDataValueChanged, true);
            app.GraphData.Enable = 'off';
            app.GraphData.Text = 'Graph Data';
            app.InputTempCGauge = uigauge(app.UIFigure, 'semicircular');
            app.InputTempCGauge.Limits = [10 50];
            app.InputTempCGauge.Enable = 'off';
            app.OutputTempCGauge = uigauge(app.UIFigure, 'semicircular');
            app.OutputTempCGauge.Limits = [10 50];
            app.OutputTempCGauge.Enable = 'off';
            app.RelativeHumidityGauge = uigauge(app.UIFigure, 'semicircular');
            app.RelativeHumidityGauge.Limits = [20 80];
            app.RelativeHumidityGauge.Enable = 'off';
            app.CurrentAGauge = uigauge(app.UIFigure, 'linear');
            app.CurrentAGauge.Limits = [0 5];
            app.CurrentAGauge.Enable = 'off';
            app.CurrentAGaugeLabel = uilabel(app.UIFigure);
            app.CurrentAGaugeLabel.Enable = 'off';
            app.CurrentAGaugeLabel.Text = 'Current [A]';
            app.InputTempCGaugeLabel = uilabel(app.UIFigure);
            app.InputTempCGaugeLabel.Enable = 'off';
            app.InputTempCGaugeLabel.Text = 'Input Temp. [Cº]';
            app.OutputTempCGaugeLabel = uilabel(app.UIFigure);
            app.OutputTempCGaugeLabel.Enable = 'off';
            app.OutputTempCGaugeLabel.Text = 'Output Temp. [Cº]';
            app.RelativeHumidityGaugeLabel = uilabel(app.UIFigure);
            app.RelativeHumidityGaugeLabel.Enable = 'off';
            app.RelativeHumidityGaugeLabel.Text = ' Relative Humidity. %';
            app.GaugesButton = uibutton(app.UIFigure, 'state');
            app.GaugesButton.ValueChangedFcn = createCallbackFcn(app, @GaugesButtonValueChanged, true);
            app.GaugesButton.Enable = 'off';
            app.GaugesButton.Text = 'Gauges';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chill2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end